classdef For_Individual_culture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Nsal103cm3EditFieldLabel     matlab.ui.control.Label
        Nsal103cm3EditField          matlab.ui.control.NumericEditField
        Tumor103cm2EditFieldLabel    matlab.ui.control.Label
        Tumor103cm2EditField         matlab.ui.control.NumericEditField
        AsEditFieldLabel             matlab.ui.control.Label
        AsEditField                  matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        suitable_timeEditFieldLabel  matlab.ui.control.Label
        suitable_timeEditField       matlab.ui.control.NumericEditField
        UIAxes2                      matlab.ui.control.UIAxes
        ProportionofinfectedcellsEditFieldLabel  matlab.ui.control.Label
        ProportionofinfectedcellsEditField  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             sal=app.Nsal103cm3EditField.Value;
            tumor=app.Tumor103cm2EditField.Value;
            ktumor=app.AsEditField.Value;
           
% When there are only cancer cell culture conditions.
 function dydt2=vdp2(t2,y2)
           
                %Y21 is the average number of Salmonella invaded in a cancer cell
                %Y22 is the concentration of extracellular Salmonella
                %Y23 cancer cell density
                %Infection rate constant of y24 cancer cells
               %Y25 The amount of Salmonella replicated in tumor cells
                dydt2=[y2(2)*y2(4);
                    -y2(1);
                     0;
                     0;y2(1).^(t2*1.5)*0.69*1.5];
            end
            function inf2=infection2(sal,tumor,ktumor)
                [t2,y2]=ode45(@vdp2,[0 6],[0,sal,tumor,ktumor,0]);
                 for iq2=1:length(t2)
                     if t2(iq2)>2
        break
       
    end
     
                 end
                  for ci2=1:length(y2(:,1))
                    if ci2>iq2
                        y2(ci2,1)= y2(ci2,1)+y2(ci2-iq2,5);
                        
                    end
                  end
                   %%%%Simulated the situation where the bacteria began to replicate two hours after the Salmonella invasion, 
                   %and iq2 is the value when t2 is about 2.
         plot(app.UIAxes,t2,y2(:,1),'Color',[1 0 0])
      for vi = 1:length(t2)
          if y2(vi,2)<=0
              y2(vi,2)=y2(vi-1,2);
              y2(vi,1)=y2(vi-1,1);
          end
      end
      plot(app.UIAxes2,t2,(1-cdf('poiss',1,y2(:,1))),'Color',[1 0 0])
      
         %%Proportion of infected cells
         YY = (1-cdf('poiss',1,y2(:,1)));
         for i = 1:length(YY)
             if YY(i)>=app.ProportionofinfectedcellsEditField.Value
                 break
             end
            end
        app.suitable_timeEditField.Value=t2(i);
            end
        infection2(sal,tumor,ktumor)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Salmonella infection')
            xlabel(app.UIAxes, 'time (h)')
            ylabel(app.UIAxes, 'avenge number')
            app.Nsal103cm3EditFieldLabel = uilabel(app.UIFigure);
            app.Nsal103cm3EditFieldLabel.Text = 'Nsal(10^3/cm3)';
            app.Nsal103cm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Nsal103cm3EditField.Value = 4000;
            app.Tumor103cm2EditFieldLabel = uilabel(app.UIFigure);
            app.Tumor103cm2EditFieldLabel.Text = 'Tumor(10^3/cm2)';
            app.Tumor103cm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Tumor103cm2EditField.Value = 100;
            app.AsEditFieldLabel = uilabel(app.UIFigure);
            app.AsEditFieldLabel.Text = 'As';
            app.AsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AsEditField.Value = 0.0003;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.suitable_timeEditFieldLabel = uilabel(app.UIFigure);
            app.suitable_timeEditFieldLabel.Text = 'suitable_time';
            app.suitable_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Proportion of infected cells')
            xlabel(app.UIAxes2, 'time (h)')
            ylabel(app.UIAxes2, 'Rate')
            app.ProportionofinfectedcellsEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionofinfectedcellsEditFieldLabel.Text = 'Proportion of infected cells';
            app.ProportionofinfectedcellsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProportionofinfectedcellsEditField.Value = 0.98;
        end
    end

    methods (Access = public)

        % Construct app
        function app = For_Individual_culture

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end