classdef HZAU2018 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Nsal103cm3EditFieldLabel     matlab.ui.control.Label
        Nsal103cm3EditField          matlab.ui.control.NumericEditField
        Tumor103cm2EditFieldLabel    matlab.ui.control.Label
        Tumor103cm2EditField         matlab.ui.control.NumericEditField
        AsEditFieldLabel             matlab.ui.control.Label
        AsEditField                  matlab.ui.control.NumericEditField
        Normal103cm2EditFieldLabel   matlab.ui.control.Label
        Normal103cm2EditField        matlab.ui.control.NumericEditField
        AwEditFieldLabel             matlab.ui.control.Label
        AwEditField                  matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        suitable_timeEditFieldLabel  matlab.ui.control.Label
        suitable_timeEditField       matlab.ui.control.NumericEditField
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            sal=app.Nsal103cm3EditField.Value;
            tumor=app.Tumor103cm2EditField.Value;
            ktumor=app.AsEditField.Value;
            normal=app.Normal103cm2EditField.Value;
            knormal=app.AwEditField.Value;
           
           
            function dydt=vdp1(t,y)
           %Y1 is the average density of Salmonella in a common intracellular invasion
                %Y2 is the density of the average Salmonella infection in a cancer cell
                %Y3 is the concentration of extracellular Salmonella
                %Y4 normal cell density
                %Y5 cancer cell density
                %Y6 rate constant of normal cell infection
                %Infection rate constant of y7 cancer cells
                %Y8 The amount of Salmonella replicated in normal cells
                %y9 The amount of Salmonella replicated in tumor cells
                dydt=[y(3)*y(6);
                    y(3)*y(7);
                    -((y(3)*y(4)*y(6))+(y(3)*y(5)*y(7)));
                     0;0;0;0;
                     y(1)^(t*1.5)*0.69*1.5;
                     y(2)^(t*1.5)*0.69*1.5];
            end
            function inf=infection(sal,normal,tumor,knormal,ktumor)
                [t,y]=ode45(@vdp1,[0 12],[0,0,sal,normal,tumor,knormal,ktumor,0,0]);
                 for iq=1:length(t)
    if t(iq)>2
        break
       
    end
     
                 end
                for ci=1:length(y(:,8))
                    if ci>iq
                        y(ci,1)= y(ci,1)+y(ci-iq,8);
                        y(ci,2)= y(ci,2)+y(ci-iq,9);
                    end
                end
              %%%%Simulated the situation where the bacteria began to replicate two hours after the Salmonella invasion, 
              %and iq is the value when t is about 2.
              
         plot(app.UIAxes,t,y(:,1),t,y(:,2))
         app.UIAxes.YLim=[0,5];
            end
        infection(sal,normal,tumor,knormal,ktumor)
    XX1=1-cdf('poiss',1,y(:,2));
    XX2=1-cdf('poiss',1,y(:,1));
   XX=XX1-XX2;
   xtime=0;
   for xi = 2:length(XX)
       if XX(xi) > XX(xi-1)
           
           xtime=xi;
       end
   end
   %%%Find the time when the experimental results differ the most
   app.suitable_timeEditField.Value=t(xtime);
 plot(app.UIAxes2,t,(1-cdf('poiss',1,y(:,2))),t,(1-cdf('poiss',1,y(:,1))))
%The next step is when there are only cancer cell culture conditions.
 function dydt2=vdp2(t2,y2)
           
                %Y21 is the average number of Salmonella invaded in a cancer cell
                %Y22 is the concentration of extracellular Salmonella
                %Y23 cancer cell density
                %Infection rate constant of y24 cancer cells
               %Y25 The amount of Salmonella replicated in tumor cells
                dydt2=[y2(2)*y2(4);
                    -y2(1);
                     0;
                     0;y2(1).^(t2*1.5)*0.69*1.5];
            end
            function inf2=infection2(sal,tumor,ktumor)
                [t2,y2]=ode45(@vdp2,[0 6],[0,sal,tumor,ktumor,0]);
                 for iq2=1:length(t2)
                     if t2(iq2)>2
        break
       
    end
     
                 end
                  for ci2=1:length(y2(:,1))
                    if ci2>iq2
                        y2(ci2,1)= y2(ci2,1)+y2(ci2-iq2,5);
                        
                    end
                  end
                   %%%%Simulated the situation where the bacteria began to replicate two hours after the Salmonella invasion, 
                   %and iq2 is the value when t2 is about 2.
              
      for vi = 1:length(t2)
          if y2(vi,2)<=0
              y2(vi,2)=y2(vi-1,2);
              y2(vi,1)=y2(vi-1,1);
          end
      end
      plot(app.UIAxes3,t2,(1-cdf('poiss',1,y2(:,1))))
         %%Proportion of infected cells
            end
        
        infection2(sal,tumor,ktumor)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Salmonella infection')
            xlabel(app.UIAxes, 'time(h)')
            ylabel(app.UIAxes, 'avenge number')
            app.Nsal103cm3EditFieldLabel = uilabel(app.UIFigure);
            app.Nsal103cm3EditFieldLabel.Text = 'Nsal(10^3/cm3)';
            app.Nsal103cm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Nsal103cm3EditField.Value = 4000;
            app.Tumor103cm2EditFieldLabel = uilabel(app.UIFigure);
            app.Tumor103cm2EditFieldLabel.Text = 'Tumor(10^3/cm2)';
            app.Tumor103cm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Tumor103cm2EditField.Value = 100;
            app.AsEditFieldLabel = uilabel(app.UIFigure);
            app.AsEditFieldLabel.Text = 'As';
            app.AsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AsEditField.Value = 0.0003;
            app.Normal103cm2EditFieldLabel = uilabel(app.UIFigure);
            app.Normal103cm2EditFieldLabel.Text = 'Normal(10^3/cm2)';
            app.Normal103cm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Normal103cm2EditField.Value = 1000;
            app.AwEditFieldLabel = uilabel(app.UIFigure);
            app.AwEditFieldLabel.Text = 'Aw';
            app.AwEditField = uieditfield(app.UIFigure, 'numeric');
            app.AwEditField.Value = 3e-05;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.suitable_timeEditFieldLabel = uilabel(app.UIFigure);
            app.suitable_timeEditFieldLabel.Text = 'suitable_time';
            app.suitable_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Proportion of infected cells')
            xlabel(app.UIAxes2, 'time(h)')
            ylabel(app.UIAxes2, 'Rate')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Tumor cells only')
            xlabel(app.UIAxes3, 'time(h)')
            ylabel(app.UIAxes3, 'Rate')
        end
    end

    methods (Access = public)

        % Construct app
        function app = HZAU2018

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end