classdef salmonella_ver4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Nsal103cm3EditFieldLabel     matlab.ui.control.Label
        Nsal103cm3EditField          matlab.ui.control.NumericEditField
        Tumor103cm2EditFieldLabel    matlab.ui.control.Label
        Tumor103cm2EditField         matlab.ui.control.NumericEditField
        AsEditFieldLabel             matlab.ui.control.Label
        AsEditField                  matlab.ui.control.NumericEditField
        Normal103cm2EditFieldLabel   matlab.ui.control.Label
        Normal103cm2EditField        matlab.ui.control.NumericEditField
        AwEditFieldLabel             matlab.ui.control.Label
        AwEditField                  matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        suitable_timeEditFieldLabel  matlab.ui.control.Label
        suitable_timeEditField       matlab.ui.control.NumericEditField
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            sal=app.Nsal103cm3EditField.Value;
            tumor=app.Tumor103cm2EditField.Value;
            ktumor=app.AsEditField.Value;
            normal=app.Normal103cm2EditField.Value;
            knormal=app.AwEditField.Value;
           
           
            function dydt=vdp1(t,y)
           %y1是一个普通细胞内入侵的平均沙门氏菌数量
                %y2是一个癌细胞内入侵的平均沙门氏菌的数量
                %y3是细胞外的沙门氏菌的浓度
                %y4正常细胞的量
                %y5癌细胞的量
                %y6正常细胞侵染的速率常数
                %y7癌细胞的侵染速率常数
                %y8 普通细胞内复制的沙门氏菌的量
                %y9 肿瘤细胞内复制的沙门氏菌的量
                dydt=[y(3)*y(6);
                    y(3)*y(7);
                    -((y(3)*y(4)*y(6))+(y(3)*y(5)*y(7)));
                     0;0;0;0;
                     y(1)^(t*1.5)*0.69*1.5;
                     y(2)^(t*1.5)*0.69*1.5];
            end
            function inf=infection(sal,normal,tumor,knormal,ktumor)
                [t,y]=ode45(@vdp1,[0 12],[0,0,sal,normal,tumor,knormal,ktumor,0,0]);
                 for iq=1:length(t)
    if t(iq)>2
        break
       
    end
     
                 end
                for ci=1:length(y(:,8))
                    if ci>iq
                        y(ci,1)= y(ci,1)+y(ci-iq,8);
                        y(ci,2)= y(ci,2)+y(ci-iq,9);
                    end
                end
              %%%%模拟在沙门氏菌入侵之后两个小时细菌才开始复制的情况，iq就是t大约为2的时候的值
              
         plot(app.UIAxes,t,y(:,1),t,y(:,2))
         app.UIAxes.YLim=[0,5];
            end
        infection(sal,normal,tumor,knormal,ktumor)
    XX1=1-cdf('poiss',1,y(:,2));
    XX2=1-cdf('poiss',1,y(:,1));
   XX=XX1-XX2;
   xtime=0;
   for xi = 2:length(XX)
       if XX(xi) > XX(xi-1)
           
           xtime=xi;
       end
   end
   %%%找到实验结果差异最大的时间
   app.suitable_timeEditField.Value=t(xtime);
 plot(app.UIAxes2,t,(1-cdf('poiss',1,y(:,2))),t,(1-cdf('poiss',1,y(:,1))))
%接下来就是单纯的培养条件下的情况了。
 function dydt2=vdp2(t2,y2)
           
                %y21是一个癌细胞内入侵的平均沙门氏菌的数量
                %y22是细胞外的沙门氏菌的浓度
                %y23癌细胞的量
                %y24癌细胞的侵染速率常数
               %y25 肿瘤细胞内复制的沙门氏菌的量
                dydt2=[y2(2)*y2(4);
                    -y2(1);
                     0;
                     0;y2(1).^(t2*1.5)*0.69*1.5];
            end
            function inf2=infection2(sal,tumor,ktumor)
                [t2,y2]=ode45(@vdp2,[0 6],[0,sal,tumor,ktumor,0]);
                 for iq2=1:length(t2)
                     if t2(iq2)>2
        break
       
    end
     
                 end
                  for ci2=1:length(y2(:,1))
                    if ci2>iq2
                        y2(ci2,1)= y2(ci2,1)+y2(ci2-iq2,5);
                        
                    end
                  end
                   %%%%模拟在沙门氏菌入侵之后两个小时细菌才开始复制的情况，iq2就是t2大约为2的时候的值
              
      for vi = 1:length(t2)
          if y2(vi,2)<=0
              y2(vi,2)=y2(vi-1,2);
              y2(vi,1)=y2(vi-1,1);
          end
      end
      plot(app.UIAxes3,t2,(1-cdf('poiss',1,y2(:,1))))
         
            end
        
        infection2(sal,tumor,ktumor)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Salmonella infection')
            xlabel(app.UIAxes, 'time(h)')
            ylabel(app.UIAxes, 'avenge number')
            app.Nsal103cm3EditFieldLabel = uilabel(app.UIFigure);
            app.Nsal103cm3EditFieldLabel.Text = 'Nsal(10^3/cm3)';
            app.Nsal103cm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Nsal103cm3EditField.Value = 4000;
            app.Tumor103cm2EditFieldLabel = uilabel(app.UIFigure);
            app.Tumor103cm2EditFieldLabel.Text = 'Tumor(10^3/cm2)';
            app.Tumor103cm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Tumor103cm2EditField.Value = 100;
            app.AsEditFieldLabel = uilabel(app.UIFigure);
            app.AsEditFieldLabel.Text = 'As';
            app.AsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AsEditField.Value = 0.0003;
            app.Normal103cm2EditFieldLabel = uilabel(app.UIFigure);
            app.Normal103cm2EditFieldLabel.Text = 'Normal(10^3/cm2)';
            app.Normal103cm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Normal103cm2EditField.Value = 1000;
            app.AwEditFieldLabel = uilabel(app.UIFigure);
            app.AwEditFieldLabel.Text = 'Aw';
            app.AwEditField = uieditfield(app.UIFigure, 'numeric');
            app.AwEditField.Value = 3e-05;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.suitable_timeEditFieldLabel = uilabel(app.UIFigure);
            app.suitable_timeEditFieldLabel.Text = 'suitable_time';
            app.suitable_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Proportion of infected cells')
            xlabel(app.UIAxes2, 'time(h)')
            ylabel(app.UIAxes2, 'Rate')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Tumor cells only')
            xlabel(app.UIAxes3, 'time(h)')
            ylabel(app.UIAxes3, 'Rate')
        end
    end

    methods (Access = public)

        % Construct app
        function app = salmonella_ver4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end